classdef App_CNS < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        ClearButton                     matlab.ui.control.Button
        SuggestionAreaPanel             matlab.ui.container.Panel
        Suggestion                      matlab.ui.control.EditField
        PropertiesoftheOptimalCNSPanel  matlab.ui.container.Panel
        Communicationcost               matlab.ui.control.NumericEditField
        CommunicationcostEditFieldLabel  matlab.ui.control.Label
        Navigationperformance           matlab.ui.control.NumericEditField
        NavigationperformanceEditFieldLabel  matlab.ui.control.Label
        OfflineDesignPanel              matlab.ui.container.Panel
        Runningtime                     matlab.ui.control.NumericEditField
        RunningtimesEditFieldLabel      matlab.ui.control.Label
        PerformdynamicprogrammingButton  matlab.ui.control.Button
        SelectAgent                     matlab.ui.control.Button
        AgentY                          matlab.ui.control.NumericEditField
        CoordinateYmLabel               matlab.ui.control.Label
        AgentX                          matlab.ui.control.NumericEditField
        CoordinateXmLabel               matlab.ui.control.Label
        GeometricDistributionsGeneratorPanel  matlab.ui.container.Panel
        Generatinggeometricdistributions  matlab.ui.control.Button
        Numberofagents                  matlab.ui.control.Spinner
        Numberofagents2100Label         matlab.ui.control.Label
        OnlineSelectionPanel            matlab.ui.container.Panel
        FindtheoptimalCNSButton         matlab.ui.control.Button
        Navigationperformancerequirement  matlab.ui.control.NumericEditField
        NavigationperformancerequirementEditFieldLabel  matlab.ui.control.Label
        ParameterSettingPanel           matlab.ui.container.Panel
        Noisefactor                     matlab.ui.control.NumericEditField
        MaximumCommunicationDistanceLabel_2  matlab.ui.control.Label
        MaxCommunication                matlab.ui.control.NumericEditField
        MaximumCommunicationDistanceLabel  matlab.ui.control.Label
        Variance_bearing                matlab.ui.control.NumericEditField
        Relativebearingerrorvariancedeg2Label  matlab.ui.control.Label
        Variance_distance               matlab.ui.control.NumericEditField
        Relativedistanceerrorvariancem2Label  matlab.ui.control.Label
        Variance_GNSS                   matlab.ui.control.NumericEditField
        GNSSerrorvariancem2Label        matlab.ui.control.Label
        DisplayAreaPanel                matlab.ui.container.Panel
        UIAxes4                         matlab.ui.control.UIAxes
        UIAxes3                         matlab.ui.control.UIAxes
        UIAxes2                         matlab.ui.control.UIAxes
        UIAxes1                         matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        XY % 2-D coordinates of all the agents
        XY_selected % 2-D coordinates of the selected agent
        XY_neighbor % 2-D coordinates of the neighbors
        Bestper % The highest navigation performance corresponding to different communication cost
        EachCost % Communication cost of each relative measurement
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: Generatinggeometricdistributions
        function GeneratinggeometricdistributionsPushed(app, event)
            n = app.Numberofagents.Value;
            app.XY.Value = Distribution_generator(n); % Randomly generate geometric distributions
            xy = app.XY.Value;
            plot(app.UIAxes1,xy(:,1),xy(:,2),'b^','LineWidth',1); % Plot geometric distributions
            app.Suggestion.Value = 'The geometric distributions have been generated!';
            set(app.SelectAgent,'Enable', 'on'); % Enable the button "Select this agent"
        end

        % Button pushed function: SelectAgent
        function SelectAgentPushed(app, event)
            cla(app.UIAxes2);
            
            xy = app.XY.Value;
            app.XY_selected.Value = [app.AgentX.Value; app.AgentY.Value];
            p = app.XY_selected.Value;
            d_max = app.MaxCommunication.Value;
            [app.XY_neighbor.Value, bool] = Distribution_Selected(xy,d_max,p); % Determine whether the selected agent exists
            sxy = app.XY_neighbor.Value;
            if bool == 0 % If the selected agent does not exist
                app.Suggestion.Value = 'Error: The selected agent does not exist.';
                set(app.PerformdynamicprogrammingButton,'Enable', 'off');
                set(app.FindtheoptimalCNSButton,'Enable', 'off');
            else
                plot(app.UIAxes2,p(1),p(2),'ko',sxy(:,1),sxy(:,2),'b^','LineWidth',1); % Plot geometric distributions of the selected agent and its neighbors
                set(app.PerformdynamicprogrammingButton,'Enable', 'on');
                if ~any(sxy)
                    app.Suggestion.Value = 'Warning: There is no agent within the communication range.';
                else
                    app.Suggestion.Value = 'Selection complete!';
                end
            end
        end

        % Button pushed function: PerformdynamicprogrammingButton
        function PerformdynamicprogrammingButtonPushed(app, event)
            p = app.XY_selected.Value;
            sxy = app.XY_neighbor.Value;
            rp = app.Variance_GNSS.Value;
            rd = app.Variance_distance.Value;
            rb = app.Variance_bearing.Value;
            dmax = app.MaxCommunication.Value;
            k = app.Noisefactor.Value;

            [NPR,Cost_DP,f,Cost,UNP1,t] = DP_Selected(p,sxy,rp,rd,rb,dmax,k); % Perform dynamic programming of the selected agent

            app.Bestper.Value = f;
            app.EachCost.Value = Cost;

            plot(app.UIAxes3,NPR,Cost_DP,'-k','LineWidth',1); % Plot the minimum communication cost vs. navigation performance requirement
            app.Runningtime.Value = t;

            app.Suggestion.Value = 'The offline design CNSs have been obtained!';
            set(app.FindtheoptimalCNSButton,'Enable', 'on');
            set(app.Navigationperformancerequirement,'Limits', [UNP1,Inf]); % Determine the highet navigation performance requirement for input
        end

        % Button pushed function: ClearButton
        function ClearButtonPushed(app, event)
            cla(app.UIAxes1);
            cla(app.UIAxes2);
            cla(app.UIAxes3);
            cla(app.UIAxes4);

            app.AgentX.Value = 0;
            app.AgentY.Value = 0;
            app.Runningtime.Value = 0;
            set(app.SelectAgent,'Enable', 'off');
            set(app.PerformdynamicprogrammingButton,'Enable', 'off');

            set(app.Navigationperformancerequirement,'Limits', [0,Inf]);
            app.Navigationperformancerequirement.Value = 0;
            set(app.FindtheoptimalCNSButton,'Enable', 'off');

            app.Navigationperformance.Value = 0;
            app.Communicationcost.Value = 0;
            
            app.Suggestion.Value = '';

            clear all;
        end

        % Button pushed function: FindtheoptimalCNSButton
        function FindtheoptimalCNSButtonPushed(app, event)
            p = app.XY_selected.Value;
            sxy = app.XY_neighbor.Value;
            f = app.Bestper.Value;
            Cost = app.EachCost.Value;
            NPR = app.Navigationperformancerequirement.Value;

            [Link, NP, min_Cost] = Optimal_CNS(f,Cost,NPR); % Find the optimal CNS

            tempx = [];
            tempy = [];
            for j = 1 : size(f,1) - 1
                if Link(j) == 1
                    tempx = [tempx,p(1),sxy(j,1)];
                    tempy = [tempy,p(2),sxy(j,2)];
                end
            end
            plot(app.UIAxes4,tempx,tempy,'-r',p(1),p(2),'ko',sxy(:,1),sxy(:,2),'b^','LineWidth',1); % Plot the optimal CNS
            
            app.Navigationperformance.Value = NP;
            app.Communicationcost.Value = min_Cost;
            app.Suggestion.Value = 'The optimal CNS has been found!';
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.DisplayAreaPanel = uipanel(app.UIFigure);
            app.DisplayAreaPanel.Title = 'Display Area';
            app.UIAxes1 = uiaxes(app.DisplayAreaPanel);
            title(app.UIAxes1, 'Geometric distributions')
            xlabel(app.UIAxes1, 'Coordinate X [m]')
            ylabel(app.UIAxes1, 'Coordinate Y [m]')
            app.UIAxes1.XLim = [0 400];
            app.UIAxes1.YLim = [0 400];
            app.UIAxes1.XTick = [0 100 200 300 400];
            app.UIAxes1.YTick = [0 100 200 300 400];
            app.UIAxes2 = uiaxes(app.DisplayAreaPanel);
            title(app.UIAxes2, 'The selected agent and its neighbors')
            xlabel(app.UIAxes2, 'Coordinate X [m]')
            ylabel(app.UIAxes2, 'Coordinate Y [m]')
            app.UIAxes3 = uiaxes(app.DisplayAreaPanel);
            title(app.UIAxes3, 'The design CNSs of the selected agent')
            xlabel(app.UIAxes3, 'Navigation performance requirement')
            ylabel(app.UIAxes3, 'Minimum communication cost')
            app.UIAxes4 = uiaxes(app.DisplayAreaPanel);
            title(app.UIAxes4, 'The optimal CNS of the selected agent')
            xlabel(app.UIAxes4, 'Coordinate X [m]')
            ylabel(app.UIAxes4, 'Coordinate Y [m]')
            app.ParameterSettingPanel = uipanel(app.UIFigure);
            app.ParameterSettingPanel.Title = 'Parameter Setting';
            app.GNSSerrorvariancem2Label = uilabel(app.ParameterSettingPanel);
            app.GNSSerrorvariancem2Label.Text = 'GNSS noise variance (m^2)';
            app.Variance_GNSS = uieditfield(app.ParameterSettingPanel, 'numeric');
            app.Variance_GNSS.Limits = [1e-10 Inf];
            app.Variance_GNSS.Value = 10;
            app.Relativedistanceerrorvariancem2Label = uilabel(app.ParameterSettingPanel);
            app.Relativedistanceerrorvariancem2Label.Text = 'Relative distance noise variance (m^2)';
            app.Variance_distance = uieditfield(app.ParameterSettingPanel, 'numeric');
            app.Variance_distance.Limits = [1e-10 Inf];
            app.Variance_distance.Value = 2;
            app.Relativebearingerrorvariancedeg2Label = uilabel(app.ParameterSettingPanel);
            app.Relativebearingerrorvariancedeg2Label.Text = 'Relative bearing noise variance (deg^2)';
            app.Variance_bearing = uieditfield(app.ParameterSettingPanel, 'numeric');
            app.Variance_bearing.Limits = [1e-10 Inf];
            app.Variance_bearing.Value = 25;
            app.MaximumCommunicationDistanceLabel = uilabel(app.ParameterSettingPanel);
            app.MaximumCommunicationDistanceLabel.Text = 'Maximum communication distance (m)';
            app.MaxCommunication = uieditfield(app.ParameterSettingPanel, 'numeric');
            app.MaxCommunication.Limits = [1e-10 Inf];
            app.MaxCommunication.Value = 100;
            app.MaximumCommunicationDistanceLabel_2 = uilabel(app.ParameterSettingPanel);
            app.MaximumCommunicationDistanceLabel_2.Text = 'Noise factor (0-1)';
            app.Noisefactor = uieditfield(app.ParameterSettingPanel, 'numeric');
            app.Noisefactor.Limits = [0 1];
            app.Noisefactor.Value = 1;
            app.OnlineSelectionPanel = uipanel(app.UIFigure);
            app.OnlineSelectionPanel.Title = 'Online Selection';
            app.NavigationperformancerequirementEditFieldLabel = uilabel(app.OnlineSelectionPanel);
            app.NavigationperformancerequirementEditFieldLabel.Text = 'Navigation performance requirement';
            app.Navigationperformancerequirement = uieditfield(app.OnlineSelectionPanel, 'numeric');
            app.Navigationperformancerequirement.Limits = [0 Inf];
            app.FindtheoptimalCNSButton = uibutton(app.OnlineSelectionPanel, 'push');
            app.FindtheoptimalCNSButton.ButtonPushedFcn = createCallbackFcn(app, @FindtheoptimalCNSButtonPushed, true);
            app.FindtheoptimalCNSButton.Enable = 'off';
            app.FindtheoptimalCNSButton.Text = 'Find the optimal CNS';
            app.GeometricDistributionsGeneratorPanel = uipanel(app.UIFigure);
            app.GeometricDistributionsGeneratorPanel.Title = 'Geometric Distributions Generator';
            app.Numberofagents2100Label = uilabel(app.GeometricDistributionsGeneratorPanel);
            app.Numberofagents2100Label.Text = 'Number of agents (2-100)';
            app.Numberofagents = uispinner(app.GeometricDistributionsGeneratorPanel);
            app.Numberofagents.Limits = [2 100];
            app.Numberofagents.RoundFractionalValues = 'on';
            app.Numberofagents.Value = 80;
            app.Generatinggeometricdistributions = uibutton(app.GeometricDistributionsGeneratorPanel, 'push');
            app.Generatinggeometricdistributions.ButtonPushedFcn = createCallbackFcn(app, @GeneratinggeometricdistributionsPushed, true);
            app.Generatinggeometricdistributions.Text = 'Generate geometric distributions';
            app.OfflineDesignPanel = uipanel(app.UIFigure);
            app.OfflineDesignPanel.Title = 'Offline Design';
            app.CoordinateXmLabel = uilabel(app.OfflineDesignPanel);
            app.CoordinateXmLabel.Text = 'Coordinate X (m)';
            app.AgentX = uieditfield(app.OfflineDesignPanel, 'numeric');
            app.AgentX.Limits = [0 400];
            app.AgentX.RoundFractionalValues = 'on';
            app.CoordinateYmLabel = uilabel(app.OfflineDesignPanel);
            app.CoordinateYmLabel.Text = 'Coordinate Y (m)';
            app.AgentY = uieditfield(app.OfflineDesignPanel, 'numeric');
            app.AgentY.Limits = [0 400];
            app.AgentY.RoundFractionalValues = 'on';
            app.SelectAgent = uibutton(app.OfflineDesignPanel, 'push');
            app.SelectAgent.ButtonPushedFcn = createCallbackFcn(app, @SelectAgentPushed, true);
            app.SelectAgent.Enable = 'off';
            app.SelectAgent.Text = 'Select this agent';
            app.PerformdynamicprogrammingButton = uibutton(app.OfflineDesignPanel, 'push');
            app.PerformdynamicprogrammingButton.ButtonPushedFcn = createCallbackFcn(app, @PerformdynamicprogrammingButtonPushed, true);
            app.PerformdynamicprogrammingButton.Enable = 'off';
            app.PerformdynamicprogrammingButton.Text = 'Perform dynamic programming';
            app.RunningtimesEditFieldLabel = uilabel(app.OfflineDesignPanel);
            app.RunningtimesEditFieldLabel.Text = 'Running time (s)';
            app.Runningtime = uieditfield(app.OfflineDesignPanel, 'numeric');
            app.Runningtime.Editable = 'off';
            app.PropertiesoftheOptimalCNSPanel = uipanel(app.UIFigure);
            app.PropertiesoftheOptimalCNSPanel.Title = 'Properties of the Optimal CNS';
            app.NavigationperformanceEditFieldLabel = uilabel(app.PropertiesoftheOptimalCNSPanel);
            app.NavigationperformanceEditFieldLabel.Text = 'Navigation performance';
            app.Navigationperformance = uieditfield(app.PropertiesoftheOptimalCNSPanel, 'numeric');
            app.Navigationperformance.Editable = 'off';
            app.CommunicationcostEditFieldLabel = uilabel(app.PropertiesoftheOptimalCNSPanel);
            app.CommunicationcostEditFieldLabel.Text = 'Communication cost';
            app.Communicationcost = uieditfield(app.PropertiesoftheOptimalCNSPanel, 'numeric');
            app.Communicationcost.Editable = 'off';
            app.SuggestionAreaPanel = uipanel(app.UIFigure);
            app.SuggestionAreaPanel.Title = 'Suggestion Area';
            app.Suggestion = uieditfield(app.SuggestionAreaPanel, 'text');
            app.Suggestion.Editable = 'off';
            app.ClearButton = uibutton(app.UIFigure, 'push');
            app.ClearButton.ButtonPushedFcn = createCallbackFcn(app, @ClearButtonPushed, true);
            app.ClearButton.Text = 'Clear';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = App_CNS

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end